classdef main < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        LoGButton         matlab.ui.control.Button
        LaplacianButton   matlab.ui.control.Button
        SegmentasiLabel   matlab.ui.control.Label
        PilihCitraButton  matlab.ui.control.Button
    end

    methods (Access = public)
        function res = laplacianEdge(~, image)
            mask = [0 1 0;
                    1 -4 1;
                    0 1 0];
            res = uint8(conv2(double(rgb2gray(image)), double(mask), 'same'));
        end

        function res = logEdge(~, image)
            mask = [0 0 -1 0 0;
                    0 -1 -2 -1 0;
                    -1 -2 16 -2 -1;
                    0 -1 -2 -1 0;
                    0 0 -1 0 0];
            res = uint8(conv2(double(rgb2gray(image)), double(mask), 'same'));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

        end

        % Button pushed function: PilihCitraButton
        function PilihCitraButtonPushed(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.SegmentasiLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: LaplacianButton
        function JalankanLaplacian(comp, event)
            try
                image = imread(comp.SegmentasiLabel.Text);

                % Initial image
                figure("Name", "Citra Input");
                imshow(image);

                % Laplacian
                res = comp.laplacianEdge(image);
                figure("Name", "Citra Hasil");
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: LoGButton
        function JalankanLoG(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % Initial image
                figure("Name", "Citra Input");
                imshow(image);

                % LoG
                res = comp.logEdge(image);
                figure("Name", "Citra Hasil");
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 506 373];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PilihCitraButton = uibutton(comp, 'push');
            comp.PilihCitraButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PilihCitraButtonPushed, true);
            comp.PilihCitraButton.Text = 'Pilih Citra';
            comp.SegmentasiLabel = uilabel(comp);
            comp.SegmentasiLabel.Text = 'Pilih citra input untuk dilakukan segmentasi';
            comp.LaplacianButton = uibutton(comp, 'push');
            comp.LaplacianButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanLaplacian, true);
            comp.LaplacianButton.Text = 'Laplacian';
            comp.LoGButton = uibutton(comp, 'push');
            comp.LoGButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanLoG, true);
            comp.LoGButton.Text = 'LoG';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end